classdef ieCameraDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        menuFile                   matlab.ui.container.Menu
        menuFileRename             matlab.ui.container.Menu
        LoadCameraDesignMenu       matlab.ui.container.Menu
        SaveCameraDesignMenu       matlab.ui.container.Menu
        menuFileFontSize           matlab.ui.container.Menu
        menuFileWaitBar            matlab.ui.container.Menu
        menuFileRefresh            matlab.ui.container.Menu
        menuLoadSession            matlab.ui.container.Menu
        menuFileSave               matlab.ui.container.Menu
        menuFileSaveAs             matlab.ui.container.Menu
        menuFileClose              matlab.ui.container.Menu
        menuFileSaveClose          matlab.ui.container.Menu
        SettingsMenu               matlab.ui.container.Menu
        mnuChooseClassifier        matlab.ui.container.Menu
        mnuSetNumClasses           matlab.ui.container.Menu
        mnuMaxImageSize            matlab.ui.container.Menu
        mnuCustomIcons             matlab.ui.container.Menu
        mnuTools                   matlab.ui.container.Menu
        mnuSaveWindowPositions     matlab.ui.container.Menu
        mnuRestoreWindowPositions  matlab.ui.container.Menu
        menuHelp                   matlab.ui.container.Menu
        menuHelpImageval           matlab.ui.container.Menu
        menuHelpDocumentation      matlab.ui.container.Menu
        menuHelpFunctions          matlab.ui.container.Menu
        mnuInstallFontHelp         matlab.ui.container.Menu
        mnuAbout                   matlab.ui.container.Menu
        GetsomeimagesPanel         matlab.ui.container.Panel
        btnLabeledDatasets         matlab.ui.control.Button
        FromthewebButton           matlab.ui.control.Button
        EvaluateyourdesignsPanel   matlab.ui.container.Panel
        lblScores                  matlab.ui.control.Label
        imgImageExplorerIcon_6     matlab.ui.control.Image
        btnShowScoreTable          matlab.ui.control.Button
        btnEvaluate                matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        Image4                     matlab.ui.control.Image
        Image3                     matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        text1                      matlab.ui.control.Label
        uipanel1                   matlab.ui.container.ButtonGroup
        imgImageExplorerIcon_7     matlab.ui.control.Image
        imgImageExplorerIcon_5     matlab.ui.control.Image
        imgImageExplorerIcon_4     matlab.ui.control.Image
        imgImageExplorerIcon_3     matlab.ui.control.Image
        imgImageExplorerIcon_2     matlab.ui.control.Image
        imgImageExplorerIcon       matlab.ui.control.Image
        btnRefreshSettings         matlab.ui.control.Button
        lblStatus                  matlab.ui.control.Label
        lblTweak                   matlab.ui.control.Label
        lblIP                      matlab.ui.control.Label
        lblSensor                  matlab.ui.control.Label
        lblOptics                  matlab.ui.control.Label
        btnTweak                   matlab.ui.control.Button
        btnProcessor               matlab.ui.control.Button
        btnDesignSensor            matlab.ui.control.Button
        btnOI                      matlab.ui.control.Button
        btnScene                   matlab.ui.control.Button
        txtMessage                 matlab.ui.control.Label
    end

    properties (Access = private) % for now at least
        tweakTemplateFileName;
        tweakFileFolderName;
        tweakFileName;
        maxImageResolution = 1024; % largest image we'll allow
        customCameraIcon = 'Camera-n-brush-pd.png';
    end

    properties (Access = public) % so that we can pass info to the scoring window
        scoreTable = [];
        ourClassifier = 'resnet50';
        scoreClasses = 5;
        % we need to save these so we can pass them to the score detail app
        oi;
        sensor;
        ip;
    end
        
    methods (Access = private)
        function refreshMain(app)
            % Called by routines in this function
            
            % Check on File | Wait bar menu item
            if ieSessionGet('wait bar')
                app.menuFileWaitBar.Checked = 'on';
            else
                app.menuFileWaitBar.Checked = 'off';
            end
            
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.CloseRequestFcn = 'ieMainClose';
        end

        function refreshSettings(app)
            temp = ieGetObject('oi');
            if ~isempty(temp)
                app.oi = temp;
            else
                app.oi = oiCreate;
            end
            temp = ieGetObject('optics');
            if ~isempty(temp)
                app.lblOptics.Text = temp.name;
            end
            temp = ieGetObject('sensor');
            if ~isempty(temp)
                app.sensor = temp;
            else
                app.sensor = sensorCreate;
            end
            app.lblSensor.Text = app.sensor.name;

            temp = ieGetObject('ip');
            if ~isempty(temp)
                app.ip = temp;
            else
                app.ip = ipCreate;
            end
            app.lblIP.Text = app.ip.name;

            app.lblTweak.Text = app.tweakFileName;
        end
        
        function ImageFileClickedCallback(app,event)
            try % hope we get a click on the right thing:)
                if event.Indices(2) == 2 % right column
                   nextCellIs = event.Source.Data(event.Indices(1), event.Indices(2)+1);
                   if isfile(nextCellIs)
                       % set image file!
                       app.imgPreview.ImageSource = nextCellIs;
                   end
                end
            catch
                uialert(app.figure1, "Click on one of the filenames to see the image.");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ieCamDesignW_OpeningFcn(app, varargin)
            
            app.figure1.Name = "Camera Design & Simulation for AI & Robotics";
            % for our new windows let's set custom icons to true by default
            if ~verLessThan('matlab','9.9') && getpref('ISET','customicons', true)
                app.figure1.Icon = app.customCameraIcon;
                app.mnuCustomIcons.Checked = true;
            else
                app.mnuCustomIcons.Checked = false;
            end
            dbApp = ieSessionGet('camdesign window');           
            if isempty(dbApp) || ~isvalid(dbApp)
                % Store this app in the database
                ieSessionSet('camdesign window',app);
            end

            % If the person has set a position and size preference, put the window
            % there
            ISETprefs = getpref('ISET');
            if isfield(ISETprefs,'wPos')
                wPos = ISETprefs.wPos;
                if ~isempty(wPos{6}) % this is so gross!
                    movegui(app.figure1,wPos{6});
                else
                    movegui(app.figure1,"northwest");
                end
            end
            refreshMain(app);
            
            % set up stuff we need:
            app.tweakTemplateFileName = fullfile(isetRootPath, "gui", "templates", "cameraTweak.template");
            app.tweakFileFolderName = fullfile(isetRootPath, "local", "data", "custom");
            app.tweakFileName = fullfile(app.tweakFileFolderName, "cameraTweak.m");
            if isfile(app.tweakFileName)
                app.lblTweak.Text = app.tweakFileName;
            end
            % show whatever the current default settings are:
            app.refreshSettings();   
            
            if isdeployed
                app.btnTweak.Enable = false;
                app.btnTweak.Tooltip = "Adding Matlab code is only supported under full Matab installs.";
            end
            
            return
        end

        % Button pushed function: btnOI
        function btnOI_Callback(app, event)
            
            % Optics button
            app.figure1.Pointer = 'watch';
            oiWindow;
            app.figure1.Pointer = 'arrow';

        end

        % Button pushed function: btnProcessor
        function btnProcessor_Callback(app, event)
            
            app.figure1.Pointer = 'watch';
            ipWindow;
            app.figure1.Pointer = 'arrow';

        end

        % Button pushed function: btnScene
        function btnScene_Callback(app, event)
            
            % Scene button
            app.figure1.Pointer = 'watch';
            sceneWindow;
            app.figure1.Pointer = 'arrow';

        end

        % Button pushed function: btnDesignSensor
        function btnDesignSensor_Callback(app, event)
            
            % Sensor button
            app.figure1.Pointer = 'watch';
            sensorWindow;
            app.figure1.Pointer = 'arrow';

        end

        % Menu selected function: menuFileClose
        function menuFileClose_Callback(app, event)
            
            % File | Close
            ieMainClose;

        end

        % Menu selected function: menuFileFontSize
        function menuFileFontSize_Callback(app, event)
            
            % File | Font Size
            % Change font size but to what?
            ieFontSizeSet(app.figure1);
            
            % Keep the big ISET big despite changing the other text
            app.text1.FontSize = 32;
            app.txtISET.FontSize = 18;
            
            refreshMain(app);
            return
        end

        % Menu selected function: menuFileRefresh
        function menuFileRefresh_Callback(app, event)
            
            % File | Refresh
            refreshMain(app);

        end

        % Menu selected function: menuFileRename
        function menuFileRename_Callback(app, event)
            
            % File | Rename session            
            sessionName = ieReadString('Select session name');
            if isempty(sessionName), return; end
            
            [~,fname,e] = fileparts(sessionName);
            if ~strcmp(e,'.mat'), e = '.mat'; fname = [fname,e]; end
            
            ieSessionSet('sessionname',fname);
            refreshMain(app);

        end

        % Menu selected function: menuFileSaveAs
        function menuFileSaveAs_Callback(app, event)

            % File | Save As
            fullName = vcSelectDataFile('session','w','mat');
            [sessionDir,fname] = fileparts(fullName);
            ieSessionSet('sessionname',fname);
            ieSessionSet('sessiondir',sessionDir);
            vcSaveSESSION(fname);
            refreshMain(app);

        end

        % Menu selected function: menuFileSaveClose
        function menuFileSaveClose_Callback(app, event)

            % File | Close and Save
            vcSaveSESSION;
            ieMainClose;

        end

        % Menu selected function: menuFileSave
        function menuFileSave_Callback(app, event)
            
            % File | Save
            try
                vcSaveSESSION;
            catch
                uialert(app.figure1, "Failed to save session","Error");
            end
        end

        % Menu selected function: menuFileWaitBar
        function menuFileWaitBar_Callback(app, event)
            
            % File | Waitbar
            % Toggle state of waitbar
            
            % h = guihandles;
            b = ieSessionGet('wait bar');
            if b
                ieSessionSet('wait bar',false);
            %     set(h.menuFileWaitBar,'Checked','off');
            else
                ieSessionSet('wait bar',true);
            %     set(h.menuFileWaitBar,'Checked','on');
            end
            refreshMain(app);

        end

        % Menu selected function: menuFile
        function menuFile_Callback(app, event)
            
        end

        % Menu selected function: menuHelpDocumentation
        function menuHelpDocumentation_Callback(app, event)
            
            % Help | Documentation (web)
            ieManualViewer('imageval code');

        end

        % Menu selected function: menuHelpFunctions
        function menuHelpFunctions_Callback(app, event)
            
            % Help | Documentation (web)
            ieManualViewer('iset functions');

        end

        % Menu selected function: menuHelpImageval
        function menuHelpImageval_Callback(app, event)
            
            % Help | ImagEval (home)
            ieManualViewer('imageval');

        end

        % Menu selected function: menuHelp
        function menuHelp_Callback(app, event)
            
        end

        % Menu selected function: menuLoadSession
        function menuLoadSession_Callback(app, event)
            
            % File | Load session
            
            sessionFileName = vcSelectDataFile('session','r','mat');
            if ~isempty(sessionFileName) && isfile(sessionFileName)
                cmd = ['load ',sessionFileName]; eval(cmd);
                disp(iePrintSessionInfo);
                refreshMain(app);
            end
        end

        % Button pushed function: FromthewebButton
        function FromthewebButtonPushed(app, event)
            webImageBrowser();
        end

        % Button pushed function: btnEvaluate
        function btnEvaluateButtonPushed(app, event)
            % now we can try to test our camera on some images
            
            % first we need to update our notion of the pipeline
            app.refreshSettings(); % get current objects
                        
            if ~isdeployed && isfile(app.tweakFileName)
                % we want to allow the user to run their own custom code
                % if we store in local, tweak is not in our path?
                try
                    defdir = pwd;
                    chdir(app.tweakFileFolderName);
                    [app.oi, app.sensor, app.ip] = cameraTweak('oi', app.oi, 'sensor', app.sensor, 'ip', app.ip);
                    chdir(defdir);
                catch
                    chdir(defdir);
                end
            end

            % show a status bar, but not sure how we arrange a callback to
            % set it as we run without a callback up to the app, which I
            % hate to have to do:)
            
            showProgress = uiprogressdlg(app.figure1,"Message","Running ISET pipeline & classifier...", "Title","Classifier progress","Icon","none", "Indeterminate","on");
            [scoreStats, app.scoreTable] = irClassify('oi', app.oi, 'sensor', app.sensor, 'ip', app.ip, 'classifier',app.ourClassifier, 'scoreClasses',app.scoreClasses,'progDialog',showProgress);
            close(showProgress);
            
            if ~isempty(scoreStats)
                uialert(app.figure1,sprintf("Your camera scored %d out of a possible %d on %d images", scoreStats(1), scoreStats(2), scoreStats(3)),"Scoring your camera design", "Icon","info");
                app.lblScores.Text = sprintf("%d out of %d on %d images", scoreStats(1), scoreStats(2), scoreStats(3));
                app.scoreTable = app.scoreTable;
            else
                app.lblScores.Text = "No scores recorded.";
                uialert(app.figure1,"No score", "Something went wrong.");
            end
        end

        % Button pushed function: btnTweak
        function btnTweakButtonPushed(app, event)
            % eventually it might be fun to let the user choose their own file
            % but for now let's just pre-populate one with a template!
            % app.tweakFileName = uigetfile("*.m", "Select the Matlab script you've set up to customize the parameters of your camera", "cameratweak.m");
            if ~isfolder(app.tweakFileFolderName)
                mkdir(app.tweakFileFolderName);
            end
            
            if ~isfile(app.tweakFileName)
                copyfile(app.tweakTemplateFileName, app.tweakFileName);
            end
            tweakCFName = char(app.tweakFileName);
            edit(tweakCFName);
            app.lblTweak.Text = app.tweakFileName; 
        end

        % Button pushed function: btnRefreshSettings
        function btnRefreshSettingsButtonPushed(app, event)
            app.refreshSettings(); % still seems kind of stupid to pass app in to app:)
        end

        % Button pushed function: btnShowScoreTable
        function btnShowScoreTableButtonPushed(app, event)
            
            ieScoreWindow(app); % pass our object to the ScoreWindow so it can see our stuff
            
        end

        % Menu selected function: mnuChooseClassifier
        function mnuChooseClassifierMenuSelected(app, event)
            list = {'googlenet','resnet50','vgg19', 'squeezenet', 'customnet'};
            [indx,tf] = listdlg('ListString',list,'SelectionMode','single');
            if ~isempty(indx)
                chooseClassifier = list{indx};
                if tf == 1
                    app.ourClassifier = chooseClassifier;
                end
            end
        end

        % Menu selected function: mnuSetNumClasses
        function mnuSetNumClassesMenuSelected(app, event)
            numClasses = inputdlg("Enter number of classes to score:","Set Number of Scoring Classes");
            if ~isempty(numClasses)
                app.scoreClasses = str2double(numClasses{1});
            end
        end

        % Menu selected function: mnuAbout
        function mnuAboutMenuSelected(app, event)
            uialert(app.figure1,"ISET is an open source project from Stanford University and ImageVal. The AI Camera Designer app has been co-developed using ISET by David Cardinal, Brian Wandell, and Joyce Farrell.", ...
                "About the Image Systems Engineering AI Camera Designer","Icon","gui\images\Stanford Logo.png");
        end

        % Menu selected function: SaveCameraDesignMenu
        function SaveCameraDesignMenuSelected(app, event)
            % user wants us to save their current camera design (oi,
            % sensor, ip)
            
            % first make a camera object that includes everything
            camera = cameraCreate('current');
            % vcSave doesn't allow us to pick a starting directory
            %   maybe it should, but until then we do it here
            cameraFolder = fullfile(isetRootPath, "local", "cameras");
            if ~isfolder(cameraFolder)
                mkdir(cameraFolder);
            end
            [cameraFileName, cameraFilePath] = uiputfile(fullfile(cameraFolder,"*.mat"),"Choose a filename for your Camera");
            if ~isequal(cameraFileName,0)
                vcSaveObject(camera, fullfile(cameraFilePath,cameraFileName));
            end
        end

        % Menu selected function: LoadCameraDesignMenu
        function LoadCameraDesignMenuSelected(app, event)
            
            cameraFolder = fullfile(isetRootPath, "local", "cameras");
            if ~isfolder(cameraFolder)
                mkdir(cameraFolder);
            end
            % vcImportObject is newer & does more, but currently doesn't
            % support camera objects
            [cameraFileName, cameraFilePath] = uigetfile(fullfile(cameraFolder,"*.mat"),"Select a saved Camera");
            if ~isequal(cameraFileName,0)
                vcLoadObject('camera', fullfile(cameraFilePath,cameraFileName));
            end
            
%             if exist('ourCamera', 'var')
%                 ourSensor = ourCamera.sensor;
%                 sensor = ourSensor;
%                 ieAddObject(sensor);
% 
%                 ourIP = ourCamera.ip;
%                 ip = ourIP;
%                 ieAddObject(ip);
%                 
%                 ourOI = ourCamera.vci;
%                 oi = ourOI;
%                 ieAddObject(oi);
%                 
%                 % do we need to set as defaults!
%             end
        end

        % Menu selected function: mnuInstallFontHelp
        function mnuInstallFontHelpMenuSelected(app, event)
            uialert(app.figure1,"ISETCam includes a copy of the Stanford typeface (Source Pro)," + ...
                "that if installed will be used for some of the Camera designer windows." + newline + ...
                "You can find it in the isetcam\gui\images directory, and install it as follows:" + newline + ...
                "On Windows: " + newline + ...
                "To make a new compatible font available to MATLAB, install the font in Windows by opening the folder Start->Control Panel->Fonts and selecting File->Install New Font. Then restart MATLAB and the font will be available for use."+ newline + ...
                "On Linux: " + newline + ...
                "If fonts are not available to X11, they cannot be used to plot the figure in MATLAB. To see a list of fonts available, type the following at the linux system prompt:"+ newline + ...
                "xlsfonts"+ newline + ...
                "To add fonts to X11, use the following command:"+ newline + ...
                "xset +fp <path_to_fonts_directory>","Adding Stanford Font","Icon","info");
        end

        % Menu selected function: mnuMaxImageSize
        function mnuMaxImageSizeMenuSelected(app, event)
            % large images can be slow to process
            % so give the user the option to limit the size we 
            % pass to ISET
            dims = [1 25];
            userResponse = inputdlg("Enter Maximum Image Resolution to Process:","Set Maximum H/W in Pixels", dims, {char(string(app.maxImageResolution))});
            if ~isempty(userResponse)
                userInput = str2double(userResponse{1});
                if userInput > 100 && userInput < 2048
                    app.maxImageResolution = userInput;
                else
                    msgbox("Allowed values are between 100 and 2048.");
                end
            end
        end

        % Menu selected function: mnuSaveWindowPositions
        function mnuSaveWindowPositionsMenuSelected(app, event)
            ieWindowsGet(true); % true means we want to save the positions
        end

        % Menu selected function: mnuRestoreWindowPositions
        function mnuRestoreWindowPositionsMenuSelected(app, event)
            ieWindowsSet([],true); % put back our preferences
        end

        % Menu selected function: mnuCustomIcons
        function mnuCustomIconsMenuSelected(app, event)
            if app.mnuCustomIcons.Checked
                setpref('ISET','customicons', false);
                app.mnuCustomIcons.Checked = false;
            else
                setpref('ISET','customicons', true);
                app.mnuCustomIcons.Checked = true;
                if ~verLessThan('matlab','9.9') && getpref('ISET','customicons', false)
                    app.figure1.Icon = app.customCameraIcon;
                end
            end
        end

        % Button pushed function: btnLabeledDatasets
        function btnLabeledDatasetsButtonPushed(app, event)
            web('https://canvas.stanford.edu/courses/124940/files/folder/AI%20Resources','-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'ISET';
            app.figure1.Tag = 'figure1';
            app.menuFile = uimenu(app.figure1);
            app.menuFile.MenuSelectedFcn = createCallbackFcn(app, @menuFile_Callback, true);
            app.menuFile.Text = 'File';
            app.menuFile.Tag = 'menuFile';
            app.menuFileRename = uimenu(app.menuFile);
            app.menuFileRename.MenuSelectedFcn = createCallbackFcn(app, @menuFileRename_Callback, true);
            app.menuFileRename.Visible = 'off';
            app.menuFileRename.Enable = 'off';
            app.menuFileRename.Text = 'Rename session';
            app.menuFileRename.Tag = 'menuFileRename';
            app.LoadCameraDesignMenu = uimenu(app.menuFile);
            app.LoadCameraDesignMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadCameraDesignMenuSelected, true);
            app.LoadCameraDesignMenu.Text = 'Load Camera Design...';
            app.SaveCameraDesignMenu = uimenu(app.menuFile);
            app.SaveCameraDesignMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCameraDesignMenuSelected, true);
            app.SaveCameraDesignMenu.Text = 'Save Camera Design...';
            app.menuFileFontSize = uimenu(app.menuFile);
            app.menuFileFontSize.MenuSelectedFcn = createCallbackFcn(app, @menuFileFontSize_Callback, true);
            app.menuFileFontSize.Separator = 'on';
            app.menuFileFontSize.Text = 'Font Size';
            app.menuFileFontSize.Tag = 'menuFileFontSize';
            app.menuFileWaitBar = uimenu(app.menuFile);
            app.menuFileWaitBar.MenuSelectedFcn = createCallbackFcn(app, @menuFileWaitBar_Callback, true);
            app.menuFileWaitBar.Text = 'Waitbar';
            app.menuFileWaitBar.Tag = 'menuFileWaitBar';
            app.menuFileRefresh = uimenu(app.menuFile);
            app.menuFileRefresh.MenuSelectedFcn = createCallbackFcn(app, @menuFileRefresh_Callback, true);
            app.menuFileRefresh.Visible = 'off';
            app.menuFileRefresh.Enable = 'off';
            app.menuFileRefresh.Text = 'Refresh';
            app.menuFileRefresh.Tag = 'menuFileRefresh';
            app.menuLoadSession = uimenu(app.menuFile);
            app.menuLoadSession.MenuSelectedFcn = createCallbackFcn(app, @menuLoadSession_Callback, true);
            app.menuLoadSession.Visible = 'off';
            app.menuLoadSession.Enable = 'off';
            app.menuLoadSession.Text = 'Load session';
            app.menuLoadSession.Tag = 'menuLoadSession';
            app.menuFileSave = uimenu(app.menuFile);
            app.menuFileSave.MenuSelectedFcn = createCallbackFcn(app, @menuFileSave_Callback, true);
            app.menuFileSave.Visible = 'off';
            app.menuFileSave.Enable = 'off';
            app.menuFileSave.Separator = 'on';
            app.menuFileSave.Text = 'Save session';
            app.menuFileSave.Tag = 'menuFileSave';
            app.menuFileSaveAs = uimenu(app.menuFile);
            app.menuFileSaveAs.MenuSelectedFcn = createCallbackFcn(app, @menuFileSaveAs_Callback, true);
            app.menuFileSaveAs.Visible = 'off';
            app.menuFileSaveAs.Enable = 'off';
            app.menuFileSaveAs.Text = 'Save as';
            app.menuFileSaveAs.Tag = 'menuFileSaveAs';
            app.menuFileClose = uimenu(app.menuFile);
            app.menuFileClose.MenuSelectedFcn = createCallbackFcn(app, @menuFileClose_Callback, true);
            app.menuFileClose.Separator = 'on';
            app.menuFileClose.Text = 'Close';
            app.menuFileClose.Tag = 'menuFileClose';
            app.menuFileSaveClose = uimenu(app.menuFile);
            app.menuFileSaveClose.MenuSelectedFcn = createCallbackFcn(app, @menuFileSaveClose_Callback, true);
            app.menuFileSaveClose.Visible = 'off';
            app.menuFileSaveClose.Enable = 'off';
            app.menuFileSaveClose.Text = 'Save and close';
            app.menuFileSaveClose.Tag = 'menuFileSaveClose';
            app.SettingsMenu = uimenu(app.figure1);
            app.SettingsMenu.Text = 'Settings';
            app.mnuChooseClassifier = uimenu(app.SettingsMenu);
            app.mnuChooseClassifier.MenuSelectedFcn = createCallbackFcn(app, @mnuChooseClassifierMenuSelected, true);
            app.mnuChooseClassifier.Text = 'Choose Classifier...';
            app.mnuSetNumClasses = uimenu(app.SettingsMenu);
            app.mnuSetNumClasses.MenuSelectedFcn = createCallbackFcn(app, @mnuSetNumClassesMenuSelected, true);
            app.mnuSetNumClasses.Text = 'Set Number of Classes to Score...';
            app.mnuMaxImageSize = uimenu(app.SettingsMenu);
            app.mnuMaxImageSize.MenuSelectedFcn = createCallbackFcn(app, @mnuMaxImageSizeMenuSelected, true);
            app.mnuMaxImageSize.Visible = 'off';
            app.mnuMaxImageSize.Enable = 'off';
            app.mnuMaxImageSize.Text = 'Maximum Image Resolution...';
            app.mnuCustomIcons = uimenu(app.SettingsMenu);
            app.mnuCustomIcons.MenuSelectedFcn = createCallbackFcn(app, @mnuCustomIconsMenuSelected, true);
            app.mnuCustomIcons.Tooltip = {'Use a variety of icons for ISET Windows, instead of just the default Matab one.'};
            app.mnuCustomIcons.Text = 'Use Custom Window Icons';
            app.mnuTools = uimenu(app.figure1);
            app.mnuTools.Text = 'Tools';
            app.mnuSaveWindowPositions = uimenu(app.mnuTools);
            app.mnuSaveWindowPositions.MenuSelectedFcn = createCallbackFcn(app, @mnuSaveWindowPositionsMenuSelected, true);
            app.mnuSaveWindowPositions.Text = 'Save Window Positions';
            app.mnuRestoreWindowPositions = uimenu(app.mnuTools);
            app.mnuRestoreWindowPositions.MenuSelectedFcn = createCallbackFcn(app, @mnuRestoreWindowPositionsMenuSelected, true);
            app.mnuRestoreWindowPositions.Text = 'Restore Window Positions';
            app.menuHelp = uimenu(app.figure1);
            app.menuHelp.MenuSelectedFcn = createCallbackFcn(app, @menuHelp_Callback, true);
            app.menuHelp.Text = 'Help';
            app.menuHelp.Tag = 'menuHelp';
            app.menuHelpImageval = uimenu(app.menuHelp);
            app.menuHelpImageval.MenuSelectedFcn = createCallbackFcn(app, @menuHelpImageval_Callback, true);
            app.menuHelpImageval.Text = 'ImagEval home';
            app.menuHelpImageval.Tag = 'menuHelpImageval';
            app.menuHelpDocumentation = uimenu(app.menuHelp);
            app.menuHelpDocumentation.MenuSelectedFcn = createCallbackFcn(app, @menuHelpDocumentation_Callback, true);
            app.menuHelpDocumentation.Text = 'Documentation (web)';
            app.menuHelpDocumentation.Tag = 'menuHelpDocumentation';
            app.menuHelpFunctions = uimenu(app.menuHelp);
            app.menuHelpFunctions.MenuSelectedFcn = createCallbackFcn(app, @menuHelpFunctions_Callback, true);
            app.menuHelpFunctions.Text = 'Functions (web)';
            app.menuHelpFunctions.Tag = 'menuHelpFunctions';
            app.mnuInstallFontHelp = uimenu(app.menuHelp);
            app.mnuInstallFontHelp.MenuSelectedFcn = createCallbackFcn(app, @mnuInstallFontHelpMenuSelected, true);
            app.mnuInstallFontHelp.Text = 'Installing the Stanford font...';
            app.mnuAbout = uimenu(app.menuHelp);
            app.mnuAbout.MenuSelectedFcn = createCallbackFcn(app, @mnuAboutMenuSelected, true);
            app.mnuAbout.Text = 'About...';
            app.txtMessage = uilabel(app.figure1);
            app.txtMessage.Tag = 'txtMessage';
            app.txtMessage.Text = '';
            app.uipanel1 = uibuttongroup(app.figure1);
            app.uipanel1.Title = 'Design Cameras';
            app.uipanel1.Tag = 'uipanel1';
            app.btnScene = uibutton(app.uipanel1, 'push');
            app.btnScene.ButtonPushedFcn = createCallbackFcn(app, @btnScene_Callback, true);
            app.btnScene.Tag = 'btnScene';
            app.btnScene.Icon = 'SceneWindowIcon.png';
            app.btnScene.Tooltip = {'You can create scenes directly from the Image Explorer, or you can also click here to look at existing ISET scenes.'; ''; 'ISET Scenes are created from images viewed from a particular position and using a customizable set of wavelengths.'};
            app.btnScene.Text = 'See Scenes';
            app.btnOI = uibutton(app.uipanel1, 'push');
            app.btnOI.ButtonPushedFcn = createCallbackFcn(app, @btnOI_Callback, true);
            app.btnOI.Tag = 'btnOI';
            app.btnOI.Icon = 'OIWindowIcon.png';
            app.btnOI.Tooltip = {'Once you have one or more Scenes, you can visualize how they will look when viewed through specific optics.'};
            app.btnOI.Text = 'Choose Optics';
            app.btnDesignSensor = uibutton(app.uipanel1, 'push');
            app.btnDesignSensor.ButtonPushedFcn = createCallbackFcn(app, @btnDesignSensor_Callback, true);
            app.btnDesignSensor.Tag = 'btnSensorImage';
            app.btnDesignSensor.Icon = 'SensorWindowIcon.png';
            app.btnDesignSensor.Tooltip = {'The Optical Image created by viewing a Scene through Optics is then captured by a Sensor. You can visualize what the senor captures, and customize your sensor selection.'};
            app.btnDesignSensor.Text = {'Design Sensor'; ''};
            app.btnProcessor = uibutton(app.uipanel1, 'push');
            app.btnProcessor.ButtonPushedFcn = createCallbackFcn(app, @btnProcessor_Callback, true);
            app.btnProcessor.Tag = 'btnProcessor';
            app.btnProcessor.Icon = 'IPWindowIcon.png';
            app.btnProcessor.Tooltip = {'Once the low-level image is captured, further Image Processing is typically also undertaken. You can customize the processing your camera does here.'};
            app.btnProcessor.Text = 'Design IP';
            app.btnTweak = uibutton(app.uipanel1, 'push');
            app.btnTweak.ButtonPushedFcn = createCallbackFcn(app, @btnTweakButtonPushed, true);
            app.btnTweak.Tag = 'btnProcessor';
            app.btnTweak.Icon = 'CameraTemplateIcon.png';
            app.btnTweak.Tooltip = {'The Windowed UI for each component of your camera only allows limited customizations. You can use direct ISET calls and other Matlab code by editing this local file that will be run on your images before they are sent to the Classifier'; ''};
            app.btnTweak.Text = 'Customize...';
            app.lblOptics = uilabel(app.uipanel1);
            app.lblOptics.Text = '(default optics)';
            app.lblSensor = uilabel(app.uipanel1);
            app.lblSensor.Text = '(default sensor)';
            app.lblIP = uilabel(app.uipanel1);
            app.lblIP.Text = {'(default ip)'; ''};
            app.lblTweak = uilabel(app.uipanel1);
            app.lblTweak.Text = '(no custom tweak file currently in use)';
            app.lblStatus = uilabel(app.uipanel1);
            app.lblStatus.Text = 'Current Settings:';
            app.btnRefreshSettings = uibutton(app.uipanel1, 'push');
            app.btnRefreshSettings.ButtonPushedFcn = createCallbackFcn(app, @btnRefreshSettingsButtonPushed, true);
            app.btnRefreshSettings.Tooltip = {'Update the current settings shown here to reflect any changes you''ve made elsewhere to the current default optics, sensor, or ip.'};
            app.btnRefreshSettings.Text = 'Refresh settings...';
            app.imgImageExplorerIcon = uiimage(app.uipanel1);
            app.imgImageExplorerIcon.Enable = 'off';
            app.imgImageExplorerIcon.Visible = 'off';
            app.imgImageExplorerIcon.ImageSource = 'ImageBrowserIcon.png';
            app.imgImageExplorerIcon_2 = uiimage(app.uipanel1);
            app.imgImageExplorerIcon_2.Enable = 'off';
            app.imgImageExplorerIcon_2.Visible = 'off';
            app.imgImageExplorerIcon_2.ImageSource = 'SceneWindowIcon.png';
            app.imgImageExplorerIcon_3 = uiimage(app.uipanel1);
            app.imgImageExplorerIcon_3.Enable = 'off';
            app.imgImageExplorerIcon_3.Visible = 'off';
            app.imgImageExplorerIcon_3.ImageSource = 'OIWindowIcon.png';
            app.imgImageExplorerIcon_4 = uiimage(app.uipanel1);
            app.imgImageExplorerIcon_4.Enable = 'off';
            app.imgImageExplorerIcon_4.Visible = 'off';
            app.imgImageExplorerIcon_4.ImageSource = 'SensorWindowIcon.png';
            app.imgImageExplorerIcon_5 = uiimage(app.uipanel1);
            app.imgImageExplorerIcon_5.Enable = 'off';
            app.imgImageExplorerIcon_5.Visible = 'off';
            app.imgImageExplorerIcon_5.ImageSource = 'IPWindowIcon.png';
            app.imgImageExplorerIcon_7 = uiimage(app.uipanel1);
            app.imgImageExplorerIcon_7.Enable = 'off';
            app.imgImageExplorerIcon_7.Visible = 'off';
            app.imgImageExplorerIcon_7.ImageSource = 'CameraTemplateIcon.png';
            app.Panel = uipanel(app.figure1);
            app.Panel.Tooltip = {'You control every element of a simulated camera. Compare designs and see how well they perform when used by leading neural-network-based object recognition systems like Resnet and Googlenet. Come up with the perfect design for a next-generation camera for a robot or autonomous vehicle.'};
            app.text1 = uilabel(app.Panel);
            app.text1.Tag = 'text1';
            app.text1.Text = {'AI Camera Designer'; 'ISE 2020-2023'};
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = 'Vista_Lab_Logo.png';
            app.Image3 = uiimage(app.Panel);
            app.Image3.ImageSource = 'Camera-n-brush-pd.svg';
            app.Image4 = uiimage(app.Panel);
            app.Image4.ImageSource = 'mainIcon.jpg';
            app.EvaluateyourdesignsPanel = uipanel(app.figure1);
            app.EvaluateyourdesignsPanel.Title = 'Evaluate your designs';
            app.btnEvaluate = uibutton(app.EvaluateyourdesignsPanel, 'push');
            app.btnEvaluate.ButtonPushedFcn = createCallbackFcn(app, @btnEvaluateButtonPushed, true);
            app.btnEvaluate.Tag = 'btnProcessor';
            app.btnEvaluate.Icon = 'ScoreWindowIcon.png';
            app.btnEvaluate.Tooltip = {'See how your camera does at classifying images compared to classifying your original test images.'};
            app.btnEvaluate.Text = 'Test...';
            app.btnShowScoreTable = uibutton(app.EvaluateyourdesignsPanel, 'push');
            app.btnShowScoreTable.ButtonPushedFcn = createCallbackFcn(app, @btnShowScoreTableButtonPushed, true);
            app.btnShowScoreTable.Tooltip = {'When you have scores, you can click here to see the details, and how each image compared.'};
            app.btnShowScoreTable.Text = 'Details ...';
            app.imgImageExplorerIcon_6 = uiimage(app.EvaluateyourdesignsPanel);
            app.imgImageExplorerIcon_6.Enable = 'off';
            app.imgImageExplorerIcon_6.Visible = 'off';
            app.imgImageExplorerIcon_6.ImageSource = 'ScoreWindowIcon.png';
            app.lblScores = uilabel(app.EvaluateyourdesignsPanel);
            app.lblScores.Text = '(no scores yet)';
            app.GetsomeimagesPanel = uipanel(app.figure1);
            app.GetsomeimagesPanel.Title = 'Get some images';
            app.FromthewebButton = uibutton(app.GetsomeimagesPanel, 'push');
            app.FromthewebButton.ButtonPushedFcn = createCallbackFcn(app, @FromthewebButtonPushed, true);
            app.FromthewebButton.Icon = 'ImageBrowserIcon.png';
            app.FromthewebButton.Tooltip = {'Browse various data sources for images to use with ISET. You can also create local collections for further analysis.'};
            app.FromthewebButton.Text = 'From the web';
            app.btnLabeledDatasets = uibutton(app.GetsomeimagesPanel, 'push');
            app.btnLabeledDatasets.ButtonPushedFcn = createCallbackFcn(app, @btnLabeledDatasetsButtonPushed, true);
            app.btnLabeledDatasets.Icon = 'AIResourcesIcon.png';
            app.btnLabeledDatasets.Tooltip = {'We''ve gotten several already labeled datasets from Kaggle and placed them on the course Canvas site. You can download, unzip, and pick images from them for testing. '};
            app.btnLabeledDatasets.Text = 'From labeled datasets';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ieCameraDesigner(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)ieCamDesignW_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end